classdef PlataformaExperimentacion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlataformaExperimentacinUIFigure  matlab.ui.Figure
        psi                          matlab.ui.control.Label
        Label_9                      matlab.ui.control.Label
        theta                        matlab.ui.control.Label
        Label_8                      matlab.ui.control.Label
        phi                          matlab.ui.control.Label
        Label_7                      matlab.ui.control.Label
        Z                            matlab.ui.control.Label
        ZLabel                       matlab.ui.control.Label
        Y                            matlab.ui.control.Label
        YLabel                       matlab.ui.control.Label
        X                            matlab.ui.control.Label
        XLabel                       matlab.ui.control.Label
        PosicindelefectorfinalLabel  matlab.ui.control.Label
        CinemticadirectaLabel        matlab.ui.control.Label
        EditField_3                  matlab.ui.control.NumericEditField
        EditField_2                  matlab.ui.control.NumericEditField
        EditField                    matlab.ui.control.NumericEditField
        J3Slider                     matlab.ui.control.Slider
        J3SliderLabel                matlab.ui.control.Label
        J2Slider                     matlab.ui.control.Slider
        J2SliderLabel                matlab.ui.control.Label
        J1Slider                     matlab.ui.control.Slider
        J1SliderLabel                matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Robot;
    end
    
    methods (Access = public)
        function draw(app)
            xlim([-700 700])
            ylim([-700 700])
            zlim([-20  900])

            tht1=app.J1Slider.Value;
            tht2=app.J2Slider.Value;
            tht3=app.J3Slider.Value;
            
            assignin('base','tht1',tht1);
            assignin('base','tht2',tht2);
            assignin('base','tht3',tht3);
            
            set_param(gcs,'SimulationCommand','start');

            q=[tht1,tht2,tht3]
            app.UIAxes.Visible='on';
            app.Robot.plot(q)
            PMTH=app.Robot.fkine(q);
            X=[transl(PMTH);flip(tr2rpy(PMTH,'zyx')')];
            app.X.Text =string(X(1));
            app.Y.Text=string(X(2));
            app.Z.Text=string(X(3));
            app.phi.Text=string(X(4));
            app.theta.Text=string(X(5));
            app.psi.Text=string(X(6));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
load_system("EnsambleInterfaz.slx");
find_system();
L1=109; L2= 89.5; L3=144.575; L4=159.5;

ws= [-700 700 -700 700 -20 900];
plot_options = {'noshadow','nobase','noname','workspace',ws,'scale',.5,'view',[42 35], 'tilesize',2,  'ortho', 'lightpos',[2 2 10] };
L(1) = Link('revolute','alpha',    0,'a',  0,'d', L1,'offset',    0,'qlim',[0 2*pi],'modified');
L(2) = Link('revolute','alpha',-pi/2,'a',  0,'d', L2,'offset',    0,'qlim',[0 2*pi],'modified');
L(3) = Link('revolute','alpha',    0,'a',-L3,'d',  0,'offset',    0,'qlim',[0 2*pi],'modified');

app.Robot = SerialLink(L,'name','Robot1','plotopt',plot_options);

app.Robot.tool = [  0   0  -1 -L4  ;
                -1   0   0   0  ;
                 0   1   0   0  ;
                 0   0   0   1  ];
app.Robot.base=[0 -1  0   0;
                0  0 -1   0;
                1  0  0 420;
                0  0  0   1];

app.PlataformaExperimentacinUIFigure.HandleVisibility = 'callback';
        end

        % Value changed function: J1Slider
        function J1SliderValueChanged(app, event)
            value = app.J1Slider.Value;
            app.EditField.Value=value;
            draw(app);
        end

        % Value changed function: J2Slider
        function J2SliderValueChanged(app, event)
            value = app.J2Slider.Value;
             app.EditField_2.Value=value;
             draw(app);
        end

        % Value changed function: J3Slider
        function J3SliderValueChanged(app, event)
            value = app.J3Slider.Value;
             app.EditField_3.Value=value;
             draw(app);
        end

        % Callback function
        function J4SliderValueChanged(app, event)
            value = app.J4Slider.Value;
             app.EditField_4.Value=value;
             draw(app);
        end

        % Callback function
        function J5SliderValueChanged(app, event)
            value = app.J5Slider.Value;
             app.EditField_5.Value=value;
             draw(app);
        end

        % Callback function
        function J6SliderValueChanged(app, event)
            value = app.J6Slider.Value;
             app.EditField_6.Value=value;
             draw(app);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
             app.J1Slider.Value=value;
             draw(app);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
              app.J2Slider.Value=value;
              draw(app);
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
              app.J3Slider.Value=value;
              draw(app);
        end

        % Callback function
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
              app.J4Slider.Value=value;
              draw(app);
        end

        % Callback function
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
              app.J5Slider.Value=value;
              draw(app);
        end

        % Callback function
        function EditField_6ValueChanged(app, event)
            value = app.EditField_6.Value;
              app.J6Slider.Value=value;
              draw(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PlataformaExperimentacinUIFigure = uifigure('Visible', 'off');
            app.PlataformaExperimentacinUIFigure.Name = 'Plataforma Experimentación';
            app.PlataformaExperimentacinUIFigure.Icon = 'icon.png';
            app.UIAxes = uiaxes(app.PlataformaExperimentacinUIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.J1SliderLabel = uilabel(app.PlataformaExperimentacinUIFigure);
            app.J1SliderLabel.Text = 'J1';
            app.J1Slider = uislider(app.PlataformaExperimentacinUIFigure);
            app.J1Slider.Limits = [-6.28318530717959 6.28318530717959];
            app.J1Slider.ValueChangedFcn = createCallbackFcn(app, @J1SliderValueChanged, true);
            app.J2SliderLabel = uilabel(app.PlataformaExperimentacinUIFigure);
            app.J2SliderLabel.Text = 'J2';
            app.J2Slider = uislider(app.PlataformaExperimentacinUIFigure);
            app.J2Slider.Limits = [-6.28318530717959 6.28318530717959];
            app.J2Slider.ValueChangedFcn = createCallbackFcn(app, @J2SliderValueChanged, true);
            app.J3SliderLabel = uilabel(app.PlataformaExperimentacinUIFigure);
            app.J3SliderLabel.Text = 'J3';
            app.J3Slider = uislider(app.PlataformaExperimentacinUIFigure);
            app.J3Slider.Limits = [-1.57910741847106 1.57910741847106];
            app.J3Slider.ValueChangedFcn = createCallbackFcn(app, @J3SliderValueChanged, true);
            app.EditField = uieditfield(app.PlataformaExperimentacinUIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.PlataformaExperimentacinUIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3 = uieditfield(app.PlataformaExperimentacinUIFigure, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.CinemticadirectaLabel = uilabel(app.PlataformaExperimentacinUIFigure);
            app.CinemticadirectaLabel.Text = 'Cinemática directa';
            app.PosicindelefectorfinalLabel = uilabel(app.PlataformaExperimentacinUIFigure);
            app.PosicindelefectorfinalLabel.Text = 'Posición del efector final';
            app.XLabel = uilabel(app.PlataformaExperimentacinUIFigure);
            app.XLabel.Text = 'X:';
            app.X = uilabel(app.PlataformaExperimentacinUIFigure);
            app.X.Text = '';
            app.YLabel = uilabel(app.PlataformaExperimentacinUIFigure);
            app.YLabel.Text = 'Y:';
            app.Y = uilabel(app.PlataformaExperimentacinUIFigure);
            app.Y.Text = '';
            app.ZLabel = uilabel(app.PlataformaExperimentacinUIFigure);
            app.ZLabel.Text = 'Z:';
            app.Z = uilabel(app.PlataformaExperimentacinUIFigure);
            app.Z.Text = '';
            app.Label_7 = uilabel(app.PlataformaExperimentacinUIFigure);
            app.Label_7.Text = 'φ:';
            app.phi = uilabel(app.PlataformaExperimentacinUIFigure);
            app.phi.Text = '';
            app.Label_8 = uilabel(app.PlataformaExperimentacinUIFigure);
            app.Label_8.Text = 'θ:';
            app.theta = uilabel(app.PlataformaExperimentacinUIFigure);
            app.theta.Text = '';
            app.Label_9 = uilabel(app.PlataformaExperimentacinUIFigure);
            app.Label_9.Text = 'ψ:';
            app.psi = uilabel(app.PlataformaExperimentacinUIFigure);
            app.psi.Text = '';
            % Show the figure after all components are created
            app.PlataformaExperimentacinUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlataformaExperimentacion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlataformaExperimentacinUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlataformaExperimentacinUIFigure)
        end
    end
end